classdef RNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumberofNetstoTestEditFieldLabel  matlab.ui.control.Label
        NumNets                         matlab.ui.control.NumericEditField
        NeuralNetStudioLabel            matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        SetButton                       matlab.ui.control.Button
        OtherParametersLabel            matlab.ui.control.Label
        ThresholdSteepnessEditFieldLabel  matlab.ui.control.Label
        ThresholdSteepness              matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel      matlab.ui.control.Label
        LearningRate                    matlab.ui.control.NumericEditField
        RunButton                       matlab.ui.control.Button
        ExperimentalDataFileEditFieldLabel  matlab.ui.control.Label
        ExperimentalData                matlab.ui.control.EditField
        TrainingDataFileEditFieldLabel  matlab.ui.control.Label
        TrainingData                    matlab.ui.control.EditField
        DropDown                        matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ydata % y-axis data for the figure
        xdata % x-axis data for the figure
        ydata2 % y-axis data for the figure
    end


    methods (Access = private)

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            % Update the number of rows of the table
            n = app.NumNets.Value;
            iterations = zeros(n,1);
            memLength = zeros(n,1);
            hiddenLayers = zeros(n,1);
            units = zeros(n,1);
            app.UITable.Data = [iterations memLength hiddenLayers units];
            
            % Update selections on dropdown menu
            if n > 1
                for i = 2:n
                    app.DropDown.Items{i} = ['Simulation ' num2str(i)];
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            iterations = app.UITable.Data(:,1);
            memLength = app.UITable.Data(:,2);
            hiddenLayers = app.UITable.Data(:,3);
            units = app.UITable.Data(:,4);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Main_AppDesigner
            
            % Plot Simulation 1 Results
             % Building filename
            parameters = app.UITable.Data;
            name = ['T' num2str(parameters(1,1)) 'f' num2str(parameters(1,2)) 'D1N' num2str(parameters(1,3)) 'M' num2str(parameters(1,4)) '.mat'];
            % Loading data for Figure 1
            load(name); app.ydata = outputs_1(1,:); app.ydata2 = outputs_1(2,:);
            temp = numel(app.ydata); app.xdata = 1:temp;
            plot(app.UIAxes_2,app.xdata,app.ydata,'-k',app.xdata,app.ydata2,'-r');
            legend(app.UIAxes_2,'Network Outputs','Target Outputs');
            % Loading data for Figure 2
            app.ydata = tOutputs; app.ydata2 = FoG_out;
            temp = numel(app.ydata); app.xdata = 1:temp;
            plot(app.UIAxes,app.xdata,app.ydata,'-k',app.xdata,app.ydata2,'-r');
            legend(app.UIAxes,'Target Outputs','Network Outputs');
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            parameters = app.UITable.Data;
            
            % Load data into figure
            temp = value - 'Simulation 0';
            temp = temp(end); % Obtaining simulation number
            
            % Building filename
            name = ['T' num2str(parameters(temp,1)) 'f' num2str(parameters(temp,2)) 'D1N' num2str(parameters(temp,3)) 'M' num2str(parameters(temp,4)) '.mat'];
            % Loading data for Figure 1
            load(name); app.ydata = outputs_1(1,:); app.ydata2 = outputs_1(2,:);
            temp = numel(app.ydata); app.xdata = 1:temp;
            plot(app.UIAxes_2,app.xdata,app.ydata,'-k',app.xdata,app.ydata2,'-r');
            legend(app.UIAxes_2,'Network Outputs','Target Outputs');
            
            % Load data into figure
            temp = value - 'Simulation 0';
            temp = temp(end); % Obtaining simulation number

            % Loading data for Figure 2
            app.ydata = tOutputs; app.ydata2 = FoG_out;
            temp = numel(app.ydata); app.xdata = 1:temp;
            plot(app.UIAxes,app.xdata,app.ydata,'-k',app.xdata,app.ydata2,'-r');
            legend(app.UIAxes,'Target Outputs','Network Outputs');

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofNetstoTestEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNetstoTestEditFieldLabel.Text = 'Number of Nets to Test';
            app.NumNets = uieditfield(app.UIFigure, 'numeric');
            app.NumNets.Limits = [0 Inf];
            app.NeuralNetStudioLabel = uilabel(app.UIFigure);
            app.NeuralNetStudioLabel.Text = 'Neural Net Studio';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Iterations/Sample'; 'Memory Length'; 'Hidden Layers'; 'Units/Hidden Layer'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.OtherParametersLabel = uilabel(app.UIFigure);
            app.OtherParametersLabel.Text = 'Other Parameters';
            app.ThresholdSteepnessEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdSteepnessEditFieldLabel.Text = 'Threshold Steepness';
            app.ThresholdSteepness = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdSteepness.Limits = [0 Inf];
            app.ThresholdSteepness.Value = 1;
            app.LearningRateEditFieldLabel = uilabel(app.UIFigure);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRate = uieditfield(app.UIFigure, 'numeric');
            app.LearningRate.Limits = [0 Inf];
            app.LearningRate.Value = 0.05;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExperimentalDataFileEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentalDataFileEditFieldLabel.Text = 'Experimental Data File';
            app.ExperimentalData = uieditfield(app.UIFigure, 'text');
            app.ExperimentalData.Value = '.txt';
            app.TrainingDataFileEditFieldLabel = uilabel(app.UIFigure);
            app.TrainingDataFileEditFieldLabel.Text = 'Training Data File';
            app.TrainingData = uieditfield(app.UIFigure, 'text');
            app.TrainingData.Value = '.txt';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Simulation 1'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Simulation 1';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trained Network Performance')
            xlabel(app.UIAxes, 'Sample')
            ylabel(app.UIAxes, 'Output')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Network Training')
            xlabel(app.UIAxes_2, 'Sample')
            ylabel(app.UIAxes_2, 'Output')
        end
    end

    methods (Access = public)

        % Construct app
        function app = RNN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end